classdef ApplicationDirigeable < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        DirigeableTab                 matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        GridLayout3                   matlab.ui.container.GridLayout
        ComponentsmanagementButton    matlab.ui.control.Button
        Plot3D                        matlab.ui.control.UIAxes
        InformationssurlesmoteursTab  matlab.ui.container.Tab
        GridLayout4                   matlab.ui.container.GridLayout
        GridLayout5                   matlab.ui.container.GridLayout
        ArriereGaucheAxes             matlab.ui.control.UIAxes
        AvantGaucheAxes               matlab.ui.control.UIAxes
        ArriereDroitAxes              matlab.ui.control.UIAxes
        AvantDroitAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dirigeable_plot

        % affichage des éléments
        faces;

        %% Fenêtre de gestion des composants
        component_windows

        % gestion des moteurs :
        motor_label
        MotorSelectionDropDown_add
        MotorSelectionDropDown_remove
        AvailableMotors;
        MotorPositions;
        Moteurs;
        MaxMoteurs = 4;
        selectedMotorAdd;

        % add or remove
        state;
    end
    
    methods (Access = private)

        function UpdateDropDown(app)
            % Gérer l'ajout de moteurs
            app.selectedMotorAdd = app.MotorSelectionDropDown_add.Value;
            if ~strcmp(app.selectedMotorAdd, 'Select Motor add')
                app.state = "add_graph";
                % Mise à jour des moteurs disponibles pour l'ajout
                app.AvailableMotors(strcmp(app.AvailableMotors, app.selectedMotorAdd)) = [];
                app.MotorSelectionDropDown_add.Items = [{'Select Motor add'}, app.AvailableMotors{:}];
                app.MotorSelectionDropDown_add.Value = 'Select Motor add'; 

                % Ajouter le moteur sélectionné à la liste des moteurs pour suppression
                currentRemoveItems = app.MotorSelectionDropDown_remove.Items;
                currentRemoveItems(strcmp(currentRemoveItems, 'Select Motor remove')) = [];
                app.MotorSelectionDropDown_remove.Items = [{'Select Motor remove'}, currentRemoveItems{:}, app.selectedMotorAdd];
                app.MotorSelectionDropDown_remove.Value = 'Select Motor remove';

                motorPos = app.MotorPositions.(app.selectedMotorAdd);
                motorPatch = patch(app.Plot3D, 'Vertices', motorPos, 'Faces', app.faces, 'FaceColor', 'g', 'FaceAlpha', 0.5);
                app.Moteurs.(app.selectedMotorAdd) = motorPatch;

                % mise à jour des graphics pour les moteurs
                app.UpdateMotorGraphics(app)
            end

            % Gérer la suppression de moteurs
            selectedMotorRemove = app.MotorSelectionDropDown_remove.Value;
            if ~strcmp(selectedMotorRemove, 'Select Motor remove')
                % Retirer le moteur sélectionné de la liste des moteurs pour suppression
                currentRemoveItems = app.MotorSelectionDropDown_remove.Items;
                currentRemoveItems(strcmp(currentRemoveItems, selectedMotorRemove)) = [];
                if isempty(currentRemoveItems)
                    currentRemoveItems = {'Select Motor remove'};
                else
                    currentRemoveItems = [{'Select Motor remove'}, currentRemoveItems{:}];
                end
                app.MotorSelectionDropDown_remove.Items = currentRemoveItems;
                app.MotorSelectionDropDown_remove.Value = 'Select Motor remove';

                % Ajouter le moteur sélectionné à la liste des moteurs pour ajout
                app.AvailableMotors{end+1} = selectedMotorRemove;
                app.MotorSelectionDropDown_add.Items = [{'Select Motor add'}, app.AvailableMotors{:}];
                app.MotorSelectionDropDown_add.Value = 'Select Motor add';

                % Supprimer la boîte du moteur retiré
                delete(app.Moteurs.(selectedMotorRemove)); % Suppression de la boîte du graphique
                app.Moteurs = rmfield(app.Moteurs, selectedMotorRemove); % Suppression de la structure des moteurs affichés
            end
        end
        
        function UpdateMotorGraphics(app)
            disp("nomnomnomnomnom")
            if app.selectedMotorAdd == "AvantDroit" && app.state == "add_graph"
                grid(app.AvantDroitAxes, "on")
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Définition de dirigeable sur le graphique Plot3D
            a = 3;
            b = 0.75; 
            c = 0.75;
            [theta, phi] = meshgrid(linspace(0, 2*pi, 50), linspace(0, pi, 50));
            
            x = a * sin(phi) .* cos(theta);
            y = b * sin(phi) .* sin(theta);
            z = c * cos(phi);

            hold(app.Plot3D, 'on');
            app.dirigeable_plot =  surf(app.Plot3D, x, y, z, ...
                                        'FaceColor','blue', ...
                                        'FaceAlpha',0.25, ...
                                        'EdgeColor','none');

            view(app.Plot3D, 3);
            grid(app.Plot3D, 'on');
            xlim(app.Plot3D, [-3, 3]);
            ylim(app.Plot3D, [-2, 2]);
            zlim(app.Plot3D, [-2, 2]);
            hold(app.Plot3D, 'off');

            % définition des faces pour afficher les composents
            app.faces = [
                1 2 3 4;
                5 6 7 8;
                1 2 6 5;
                2 3 7 6;
                3 4 8 7;
                4 1 5 8
                ];

            % Définition des moteurs
            app.Moteurs = {};
            app.AvailableMotors = {'AvantDroit', 'AvantGauche', 'ArriereDroit', 'ArriereGauche'};
            
            pos_mot = [2.1, 1.9, 0.9, 1.1, 0.05];
            app.MotorPositions = struct( ...
                'AvantDroit', [pos_mot(1), pos_mot(3), -pos_mot(5); ...
                               pos_mot(2), pos_mot(3), -pos_mot(5); ...
                               pos_mot(2), pos_mot(3), pos_mot(5); ...
                               pos_mot(1), pos_mot(3), pos_mot(5); ...
                               pos_mot(1), pos_mot(4), -pos_mot(5); ...
                               pos_mot(2), pos_mot(4), -pos_mot(5); ...
                               pos_mot(2), pos_mot(4), pos_mot(5); ...
                               pos_mot(1), pos_mot(4), pos_mot(5)], ...
                'AvantGauche', [pos_mot(1), -pos_mot(3), -pos_mot(5); ...
                                pos_mot(2), -pos_mot(3), -pos_mot(5); ...
                                pos_mot(2), -pos_mot(3), pos_mot(5); ...
                                pos_mot(1), -pos_mot(3), pos_mot(5); ...
                                pos_mot(1), -pos_mot(4), -pos_mot(5); ...
                                pos_mot(2), -pos_mot(4), -pos_mot(5); ...
                                pos_mot(2), -pos_mot(4), pos_mot(5); ...
                                pos_mot(1), -pos_mot(4), pos_mot(5)], ...
                'ArriereDroit', [-pos_mot(1), pos_mot(3), -pos_mot(5); ...
                                 -pos_mot(2), pos_mot(3), -pos_mot(5); ...
                                 -pos_mot(2), pos_mot(3), pos_mot(5); ...
                                 -pos_mot(1), pos_mot(3), pos_mot(5); ...
                                 -pos_mot(1), pos_mot(4), -pos_mot(5); ...
                                 -pos_mot(2), pos_mot(4), -pos_mot(5); ...
                                 -pos_mot(2), pos_mot(4), pos_mot(5); ...
                                 -pos_mot(1), pos_mot(4), pos_mot(5)], ...
                'ArriereGauche', [-pos_mot(1), -pos_mot(3), -pos_mot(5); ...
                                  -pos_mot(2), -pos_mot(3), -pos_mot(5); ...
                                  -pos_mot(2), -pos_mot(3), pos_mot(5); ...
                                  -pos_mot(1), -pos_mot(3), pos_mot(5); ...
                                  -pos_mot(1), -pos_mot(4), -pos_mot(5); ...
                                  -pos_mot(2), -pos_mot(4), -pos_mot(5); ...
                                  -pos_mot(2), -pos_mot(4), pos_mot(5); ...
                                  -pos_mot(1), -pos_mot(4), pos_mot(5)] ...
            );
        end

        % Button pushed function: ComponentsmanagementButton
        function ComponentsmanagementButtonPushed(app, event)
            if isempty(app.component_windows) || ~isvalid(app.component_windows)
                app.component_windows = uifigure('Name', 'Gestion des composants');
                app.component_windows.Position = [200 200 800 700];

                app.motor_label = uilabel(app.component_windows, ...
                    "Text", "COMPOSANT MOTEUR", ...
                    "FontSize", 16, ...
                    "FontWeight", "bold", ...
                    "Position", [125 550 200 200]);

                app.MotorSelectionDropDown_add = uidropdown(app.component_windows, ...
                    "Position", [50, 550, 150, 50], ...
                    "Items", [{'Select Motor add'}, app.AvailableMotors{:}], ...
                    "Value", 'Select Motor add', ...
                    "ValueChangedFcn", @(dd, event) UpdateDropDown(app));

                app.MotorSelectionDropDown_remove = uidropdown(app.component_windows, ...
                    "Position", [200, 550, 150, 50], ...
                    "Value","Select Motor remove", ...
                    "Items", "Select Motor remove", ...
                    "ValueChangedFcn", @(dd, event) UpdateDropDown(app));
            else
                figure(app.component_windows)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.DirigeableTab = uitab(app.TabGroup);
            app.DirigeableTab.Title = 'Dirigeable';
            app.GridLayout2 = uigridlayout(app.DirigeableTab);
            app.Plot3D = uiaxes(app.GridLayout2);
            title(app.Plot3D, 'Title')
            xlabel(app.Plot3D, 'X')
            ylabel(app.Plot3D, 'Y')
            zlabel(app.Plot3D, 'Z')
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.ComponentsmanagementButton = uibutton(app.GridLayout3, 'push');
            app.ComponentsmanagementButton.ButtonPushedFcn = createCallbackFcn(app, @ComponentsmanagementButtonPushed, true);
            app.ComponentsmanagementButton.Text = 'Components management';
            app.InformationssurlesmoteursTab = uitab(app.TabGroup);
            app.InformationssurlesmoteursTab.Title = 'Informations sur les moteurs';
            app.GridLayout4 = uigridlayout(app.InformationssurlesmoteursTab);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.AvantDroitAxes = uiaxes(app.GridLayout5);
            title(app.AvantDroitAxes, 'AvantDroit')
            xlabel(app.AvantDroitAxes, 'X')
            ylabel(app.AvantDroitAxes, 'Y')
            zlabel(app.AvantDroitAxes, 'Z')
            app.ArriereDroitAxes = uiaxes(app.GridLayout5);
            title(app.ArriereDroitAxes, 'ArriereDroit')
            xlabel(app.ArriereDroitAxes, 'X')
            ylabel(app.ArriereDroitAxes, 'Y')
            zlabel(app.ArriereDroitAxes, 'Z')
            app.AvantGaucheAxes = uiaxes(app.GridLayout5);
            title(app.AvantGaucheAxes, 'AvantGauche')
            xlabel(app.AvantGaucheAxes, 'X')
            ylabel(app.AvantGaucheAxes, 'Y')
            zlabel(app.AvantGaucheAxes, 'Z')
            app.ArriereGaucheAxes = uiaxes(app.GridLayout5);
            title(app.ArriereGaucheAxes, 'ArriereGauche')
            xlabel(app.ArriereGaucheAxes, 'X')
            ylabel(app.ArriereGaucheAxes, 'Y')
            zlabel(app.ArriereGaucheAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ApplicationDirigeable

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end