classdef application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        TabGroup                   matlab.ui.container.TabGroup
        Reprsentation3DTab         matlab.ui.container.Tab
        GridLayout2                matlab.ui.container.GridLayout
        GridLayout9                matlab.ui.container.GridLayout
        GenerateMomentButton       matlab.ui.control.Button
        ExportDataButton           matlab.ui.control.Button
        GridLayout8                matlab.ui.container.GridLayout
        PosGrav                    matlab.ui.control.Label
        CentredegravitLabel        matlab.ui.control.Label
        GridLayout3                matlab.ui.container.GridLayout
        GridLayout10               matlab.ui.container.GridLayout
        BallastLabel               matlab.ui.control.Label
        GridLayout11               matlab.ui.container.GridLayout
        m_ballast_4                matlab.ui.control.EditField
        Label_4                    matlab.ui.control.Label
        m_ballast_3                matlab.ui.control.EditField
        Label_3                    matlab.ui.control.Label
        m_ballast_2                matlab.ui.control.EditField
        Label_2                    matlab.ui.control.Label
        m_ballast_1                matlab.ui.control.EditField
        Label                      matlab.ui.control.Label
        GridLayout7                matlab.ui.container.GridLayout
        NacelleCheckBox            matlab.ui.control.CheckBox
        NacelleLabel               matlab.ui.control.Label
        GridLayout6                matlab.ui.container.GridLayout
        ArGRPMSlider               matlab.ui.control.Slider
        ArGRPMSliderLabel          matlab.ui.control.Label
        ArDRPMSlider               matlab.ui.control.Slider
        ArDRPMSliderLabel          matlab.ui.control.Label
        AvGRPMSlider               matlab.ui.control.Slider
        AvGRPMSliderLabel          matlab.ui.control.Label
        AvDRPMSlider               matlab.ui.control.Slider
        AvDRPMSliderLabel          matlab.ui.control.Label
        GridLayout4                matlab.ui.container.GridLayout
        Angle4                     matlab.ui.control.NumericEditField
        Angle3                     matlab.ui.control.NumericEditField
        Angle2                     matlab.ui.control.NumericEditField
        Angle1                     matlab.ui.control.NumericEditField
        AngleLabel                 matlab.ui.control.Label
        MoteurLabel                matlab.ui.control.Label
        ArriregaucheCheckBox       matlab.ui.control.CheckBox
        ArriredroitCheckBox        matlab.ui.control.CheckBox
        AvantgaucheCheckBox        matlab.ui.control.CheckBox
        AvantdroitCheckBox         matlab.ui.control.CheckBox
        GestiondescomposantsLabel  matlab.ui.control.Label
        Plot3D                     matlab.ui.control.UIAxes
        PerformanceTab             matlab.ui.container.Tab
        GridLayout5                matlab.ui.container.GridLayout
        GridLayout13               matlab.ui.container.GridLayout
        MzLabel                    matlab.ui.control.Label
        MyLabel                    matlab.ui.control.Label
        MxLabel                    matlab.ui.control.Label
        MomentrsultanteLabel       matlab.ui.control.Label
        GridLayout12               matlab.ui.container.GridLayout
        FzLabel                    matlab.ui.control.Label
        FyLabel                    matlab.ui.control.Label
        FxLabel                    matlab.ui.control.Label
        ForcersultanteLabel        matlab.ui.control.Label
        ArG_plot                   matlab.ui.control.UIAxes
        AvG_plot                   matlab.ui.control.UIAxes
        ArD_plot                   matlab.ui.control.UIAxes
        AvD_plot                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % constant
        grav = 9.80665
        V = 7
        rho_air = 1.225
        rho_helium = 0.1786
        nbr_components
        mass_dirigeable = 3

        % graphique
        dirigeable_plot
        point_CG_changed;
        point_CG_changed_text;
        faces
        patch_av_d_motor; patch_av_d_tube; patch_av_d_box; patch_grav_center_av_d
        patch_av_g_motor; patch_av_g_tube; patch_av_g_box; patch_grav_center_av_g
        patch_ar_d_motor; patch_ar_d_tube; patch_ar_d_box; patch_grav_center_ar_d
        patch_ar_g_motor; patch_ar_g_tube; patch_ar_g_box; patch_grav_center_ar_g

        norm_weight_AvD; norm_weight_AvG; norm_weight_ArD; norm_weight_ArG;
        norm_weight_nacelle; norm_weight_grappin;
        percRPM_plot_AvD; percRPM_plot_AvG; percRPM_plot_ArD; percRPM_plot_ArG; 

        patch_nacelle
        patch_grappin
        
        % rotation
        angle_1;
        angle_2;
        angle_3;
        angle_4;
        angle_vector;
        angle_1_changed = false;
        angle_2_changed = false;
        angle_3_changed = false;
        angle_4_changed = false;

        % component mass
        mass_AvD
        mass_AvG
        mass_ArD
        mass_ArG
        mass_nacelle
        mass_grappin
        mass_components
        mass_ballast_1
        mass_ballast_2
        mass_ballast_3
        mass_ballast_4
        
        CG_components
        CG_motor
        CG_total
        
        % weight motor
        weight_AvD
        weight_AvG
        weight_ArD
        weight_ArG
        weight_nacelle
        weight_grappin

        % motor %RPM
        perc_RPM_AvD
        perc_RPM_AvG
        perc_RPM_ArD
        perc_RPM_ArG

        % force et norm/plot
        f_norm_AvD
        f_norm_AvG
        f_norm_ArD
        f_norm_ArG
        thrust_AvD
        thrust_AvG
        thrust_ArD
        thrust_ArG
        thrust_AvD_norm
        thrust_AvG_norm
        thrust_ArD_norm
        thrust_ArG_norm
        P_dirigeable
        P_dirigeable_norm
        P_ballast_1
        P_ballast_2
        P_ballast_3
        P_ballast_4
        P_ballast_1_norm
        P_ballast_2_norm
        P_ballast_3_norm        
        P_ballast_4_norm
        F_B_dirigeable
        F_B_dirigeable_norm
        forces_ext
        forces_res_norm
        forces_res
        

        % moment et norm/plot
        moment
        moment_res_norm
        moment_res

        % motor_position
        position

        % position nacelle
        nacelle_position

        % position ballasts
        ballast_position
        patch_ballaste

        % battery position
        battery_position
        patch_battery
        patch_BAT_Av
        patch_BAT_Ar

        % statut
        state_av_d
        state_av_g
        state_ar_d
        state_ar_g
        state_nacelle
        state_grappin

        % centre de gravité
        grav_center

        % norme des forces
        total_weight_norm; weight_norm
        total_thrust_norm
        force_norm_x
        force_norm_y
        force_norm_z

        % fichier
        data_folder

        % distance
        distance
    end
    
    methods (Access = private)
        
        function [Vertices_motor, Vertices_tube, Vertices_block, PivotPoint, G_sm, G_m] = DefVeticesMotor(app, num)
            y_t = 0.2;
            z_t = 0.03;
            y_b = 0.4;

            if num == 1
                signe_y = 1;
                x_t1 = -0.08;
                x_t2 = 0.08;
                x_b1 = 0.1;
                x_b2 = -0.1;
            elseif num == 2
                signe_y = -1;
                x_t1 = -0.08;
                x_t2 = 0.08;
                x_b1 = 0.1;
                x_b2 = -0.1;
            elseif num == 3
                signe_y = 1;
                x_t1 = 0.08;
                x_t2 = -0.08;
                x_b1 = -0.1;
                x_b2 = 0.1;
            else
                signe_y = -1;
                x_t1 = 0.08;
                x_t2 = -0.08;
                x_b1 = -0.1;
                x_b2 = 0.1;
            end

            PivotPoint = [(app.position.x1(num) + app.position.x2(num))/2, 0, 0];

            Vertices_motor = [
                app.position.x1(num), app.position.y1(num), app.position.z1(num);
                app.position.x2(num), app.position.y1(num), app.position.z1(num);
                app.position.x2(num), app.position.y1(num), app.position.z2(num);
                app.position.x1(num), app.position.y1(num), app.position.z2(num);
                app.position.x1(num), app.position.y2(num), app.position.z1(num);
                app.position.x2(num), app.position.y2(num), app.position.z1(num);
                app.position.x2(num), app.position.y2(num), app.position.z2(num);
                app.position.x1(num), app.position.y2(num), app.position.z2(num)
                ];

            Vertices_tube = [
                app.position.x1(num) + x_t1, app.position.y1(num) - signe_y * y_t, app.position.z1(num) + z_t;
                app.position.x2(num) + x_t2, app.position.y1(num) - signe_y * y_t, app.position.z1(num) + z_t;
                app.position.x2(num) + x_t2, app.position.y1(num) - signe_y * y_t, app.position.z2(num) - z_t;
                app.position.x1(num) + x_t1, app.position.y1(num) - signe_y * y_t, app.position.z2(num) - z_t;
                app.position.x1(num) + x_t1, app.position.y2(num) - signe_y * y_t, app.position.z1(num) + z_t;
                app.position.x2(num) + x_t2, app.position.y2(num) - signe_y * y_t, app.position.z1(num) + z_t;
                app.position.x2(num) + x_t2, app.position.y2(num) - signe_y * y_t, app.position.z2(num) - z_t;
                app.position.x1(num) + x_t1, app.position.y2(num) - signe_y * y_t, app.position.z2(num) - z_t
                ];

            Vertices_block = [
                app.position.x1(num) + x_b1, app.position.y1(num) - signe_y * y_b, 2*app.position.z1(num);
                app.position.x2(num) + x_b2, app.position.y1(num) - signe_y * y_b, 2*app.position.z1(num);
                app.position.x2(num) + x_b2, app.position.y1(num) - signe_y * y_b, 2*app.position.z2(num);
                app.position.x1(num) + x_b1, app.position.y1(num) - signe_y * y_b, 2*app.position.z2(num);
                app.position.x1(num) + x_b1, app.position.y2(num) - signe_y * y_b, 2*app.position.z1(num);
                app.position.x2(num) + x_b2, app.position.y2(num) - signe_y * y_b, 2*app.position.z1(num);
                app.position.x2(num) + x_b2, app.position.y2(num) - signe_y * y_b, 2*app.position.z2(num);
                app.position.x1(num) + x_b1, app.position.y2(num) - signe_y * y_b, 2*app.position.z2(num)
                ];
            
            x_sm = ((app.position.x1(num) + app.position.x2(num))/2 + (app.position.x1(num) + x_t1 + app.position.x2(num) + x_t2)/2 + (app.position.x1(num) + x_b1 + app.position.x2(num) + x_b2)/2) / 3;
            y_sm = ((app.position.y1(num) + app.position.y2(num))/2 + (app.position.y1(num) - signe_y * y_t + app.position.y2(num) - signe_y * y_t)/2 + (app.position.y1(num) - signe_y * y_b + app.position.y2(num) - signe_y * y_b)/2) / 3;
            z_sm = ((app.position.z1(num) + app.position.z2(num))/2 + (app.position.z1(num) + z_t + app.position.z2(num) - z_t)/2 + (2*app.position.z1(num) + 2*app.position.z2(num))/2) / 3;
            
            G_sm = [x_sm, y_sm, z_sm];

            G_m = [(app.position.x1(num) + app.position.x2(num))/2; (app.position.y1(num) + app.position.y2(num))/2; (app.position.z1(num) + app.position.z2(num))/2];
        end
        
        function rot = motor_rotation(app, num)
            s = sin(app.angle_vector(num));
            c = cos(app.angle_vector(num));

            rot = [
                [c 0 s];
                [0 1 0];
                [-s 0 c]
                ];
        end

        function updateTotalCG(app)
            masses = [app.mass_dirigeable, app.mass_components];
            CGs = [app.grav_center; app.CG_components];
            
            total_mass = sum(masses);
            weighted_CG_sum = sum(CGs .* masses', 1);
            app.CG_total = weighted_CG_sum / total_mass;
            
            % Mettre à jour l'affichage du centre de gravité total si nécessaire
            disp(['Centre de gravité total: ', num2str(app.CG_total)]);
            app.PosGrav.Text = ['x = ', num2str(app.CG_total(1)), '     y = ', num2str(app.CG_total(2)), '     z = ', num2str(app.CG_total(3))];
            
            delete(app.point_CG_changed)
            delete(app.point_CG_changed_text)
            app.point_CG_changed = plot3(app.Plot3D, app.CG_total(1), app.CG_total(2), app.CG_total(3), 'o', 'MarkerSize', 4, 'MarkerEdgeColor', 'b', 'MarkerFaceColor', [0, 0, 1]);
            app.point_CG_changed_text = text(app.Plot3D, app.CG_total(1), app.CG_total(2), app.CG_total(3) + 0.05, "G'", 'FontSize', 12, 'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom');
        end
        
        function thrust = thrust_func(app, perc_RPM)
            thrust = 1.1081*1e-4 .* perc_RPM .^2 + 0.0092 .* perc_RPM - 0.0934;
        end
        
        function UpdateResultante(app)

            delete(app.F_B_dirigeable_norm) 
            app.F_B_dirigeable_norm = quiver3(app.Plot3D, app.CG_total(1), app.CG_total(2), app.CG_total(3), app.F_B_dirigeable(1), app.F_B_dirigeable(2), app.F_B_dirigeable(3), 'Color', [0.90, 0.85, 0.68], 'LineWidth', 2);

            delete(app.P_dirigeable_norm) 
            app.P_dirigeable_norm = quiver3(app.Plot3D, app.CG_total(1), app.CG_total(2), app.CG_total(3), app.P_dirigeable(1), app.P_dirigeable(2), app.P_dirigeable(3), 'Color', [0.68, 0.85, 0.90], 'LineWidth', 2);

            try
                delete(app.forces_res_norm)
                delete(app.force_norm_x)
                delete(app.force_norm_y)
                delete(app.force_norm_z)

                app.forces_ext = [app.total_thrust_norm, app.weight_norm];

                vector_norm = [app.P_dirigeable, app.forces_ext, app.F_B_dirigeable];
                app.forces_res = [sum(vector_norm(1, :)); sum(vector_norm(2, :)); sum(vector_norm(3, :))];
                
                app.forces_res_norm = quiver3(app.Plot3D, app.CG_total(1), app.CG_total(2), app.CG_total(3), app.forces_res(1), app.forces_res(2), app.forces_res(3), 'Color', 'm', 'LineWidth', 2);
                app.force_norm_x = quiver3(app.Plot3D, app.CG_total(1), app.CG_total(2), app.CG_total(3), sum(vector_norm(1, :)), 0, 0, 'Color', [1, 0, 0], 'LineWidth', 2);
                app.force_norm_y = quiver3(app.Plot3D, app.CG_total(1), app.CG_total(2), app.CG_total(3), 0, sum(vector_norm(2, :)), 0, 'Color', [1, 0, 0], 'LineWidth', 2);
                app.force_norm_z = quiver3(app.Plot3D, app.CG_total(1), app.CG_total(2), app.CG_total(3), 0, 0, sum(vector_norm(3, :)), 'Color', [1, 0, 0], 'LineWidth', 2);

                % update Labled
                app.FxLabel.Text = "Fx = " + app.forces_res(1) +  " [N]";
                app.FyLabel.Text = "Fy = " + app.forces_res(2) +  " [N]";
                app.FzLabel.Text = "Fz = " + app.forces_res(3) +  " [N]";
                
            catch error
                disp(error)
            end
        end
        
        function AddBallast(app)
            for i = 1:4
            hold(app.Plot3D, 'on')
                Vertices_ballast = [
                    app.ballast_position.x1(i), app.ballast_position.y1(i), app.ballast_position.z1(i);
                    app.ballast_position.x2(i), app.ballast_position.y1(i), app.ballast_position.z1(i);
                    app.ballast_position.x2(i), app.ballast_position.y2(i), app.ballast_position.z1(i);
                    app.ballast_position.x1(i), app.ballast_position.y2(i), app.ballast_position.z1(i);
                    app.ballast_position.x1(i), app.ballast_position.y1(i), app.ballast_position.z2(i);
                    app.ballast_position.x2(i), app.ballast_position.y1(i), app.ballast_position.z2(i);
                    app.ballast_position.x2(i), app.ballast_position.y2(i), app.ballast_position.z2(i);
                    app.ballast_position.x1(i), app.ballast_position.y2(i), app.ballast_position.z2(i);
                    ];

                app.patch_ballaste = patch(app.Plot3D, 'Vertices', Vertices_ballast, 'Faces', app.faces, 'FaceColor', 'r', 'FaceAlpha', 0.5);
            end
        end
        
        function AddBattery(app)

            for i = 1:2
                mass_battery = [0.407, 0.437];

                hold(app.Plot3D, 'on')
                Vertices_battery = [
                    app.battery_position.x1(i), app.battery_position.y1(i), app.battery_position.z1(i);
                    app.battery_position.x2(i), app.battery_position.y1(i), app.battery_position.z1(i);
                    app.battery_position.x2(i), app.battery_position.y2(i), app.battery_position.z1(i);
                    app.battery_position.x1(i), app.battery_position.y2(i), app.battery_position.z1(i);
                    app.battery_position.x1(i), app.battery_position.y1(i), app.battery_position.z2(i);
                    app.battery_position.x2(i), app.battery_position.y1(i), app.battery_position.z2(i);
                    app.battery_position.x2(i), app.battery_position.y2(i), app.battery_position.z2(i);
                    app.battery_position.x1(i), app.battery_position.y2(i), app.battery_position.z2(i);
                    ];

                app.patch_battery = patch(app.Plot3D, 'Vertices', Vertices_battery, 'Faces', app.faces, 'FaceColor', [88/255, 57/255, 39/255], 'FaceAlpha', 0.5);
                
                x_battery = (app.battery_position.x1(i) + app.battery_position.x2(i))/2;
                y_battery = (app.battery_position.y1(i) + app.battery_position.y2(i))/2;
                z_battery = (app.battery_position.y1(i) + app.battery_position.y2(i))/2;

                app.CG_components(9 + i, :) = [x_battery, y_battery, z_battery];
                app.mass_components(9 + i) = mass_battery(i);

            end
            % weight = app.mass_components()
            % app.patch_BAT_Av = 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            % Définition de dirigeable sur le graphique Plot3D
            % app.mass_dirigeable = 3;
            a = 3;
            b = 0.75; 
            c = 0.75;
            [theta, phi] = meshgrid(linspace(0, 2*pi, 50), linspace(0, pi, 50));
            
            x = a * sin(phi) .* cos(theta);
            y = b * sin(phi) .* sin(theta);
            z = c * cos(phi);

            hold(app.Plot3D, 'on');
            app.dirigeable_plot =  surf(app.Plot3D, x, y, z, ...
                                        'FaceColor','blue', ...
                                        'FaceAlpha',0.25, ...
                                        'EdgeColor','none');

            view(app.Plot3D, 3);
            grid(app.Plot3D, 'on');
            xlim(app.Plot3D, [-3.5, 3.5]);
            ylim(app.Plot3D, [-2.2, 2.2]);
            zlim(app.Plot3D, [-2, 2]);

            % Initialisation du centre de gravité
            app.grav_center = [3 - 6 * 0.41, 0, 0];
            plot3(app.Plot3D, app.grav_center(1), 0, 0, 'o', 'MarkerSize', 4, 'MarkerEdgeColor', 'k', 'MarkerFaceColor', [0.5, 0, 0.5]);
            text(app.Plot3D, app.grav_center(1), 0, 0.05, 'G', 'FontSize', 12, 'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom');
            quiver3(app.Plot3D, 0, 0, 0, 1, 0, 0, 'Color','k', 'LineWidth', 1)
            quiver3(app.Plot3D, 0, 0, 0, 0, 1, 0, 'Color','k', 'LineWidth', 1)
            quiver3(app.Plot3D, 0, 0, 0, 0, 0, 1, 'Color','k', 'LineWidth', 1)
    
            app.F_B_dirigeable = [0; 0; (app.rho_air - app.rho_helium) * app.V * app.grav];
            app.F_B_dirigeable_norm = quiver3(app.Plot3D, app.grav_center(1), app.grav_center(2), app.grav_center(3), app.F_B_dirigeable(1), app.F_B_dirigeable(2), app.F_B_dirigeable(3), 'Color', [0.90, 0.85, 0.68], 'LineWidth', 2);

            app.P_dirigeable = [0; 0; -app.mass_dirigeable * app.grav];
            app.P_dirigeable_norm = quiver3(app.Plot3D, app.grav_center(1), app.grav_center(2), app.grav_center(3), app.P_dirigeable(1), app.P_dirigeable(2), app.P_dirigeable(3), 'Color', [0.68, 0.85, 0.90], 'LineWidth', 2);

            % Définition de la face
            app.faces = [
                1 2 3 4;
                5 6 7 8;
                1 2 6 5;
                2 3 7 6;
                3 4 8 7;
                4 1 5 8
                ];


            % Chargement des positions des moteurs
            current_folder = pwd;
            app.data_folder = fullfile(current_folder, 'data/');

            try
                app.position = load(fullfile(app.data_folder, "MotorPosition.mat"));
                disp("Chargement des positions des moteurs validé.")
            catch error
                disp(error)
            end

            try
                app.nacelle_position = load(fullfile(app.data_folder, "NacellePosition.mat"));
                disp("Chargement de la position de la nacelle validé.")
            catch error
                disp(error)
            end

            try
                app.ballast_position = load(fullfile(app.data_folder, "BallastPosition.mat"));
                app.AddBallast()
                disp("Chargement de la position des ballasts.")
            catch error
                disp(error)
            end

            try
                app.battery_position = load(fullfile(app.data_folder, 'Battery.mat'));
                disp("Chargement de la position des batteries.")
                app.AddBattery()
            catch error
                disp(error)
            end

            % valeur init des angles moteur
            app.angle_1 = app.Angle1.Value;
            app.angle_2 = app.Angle2.Value;
            app.angle_3 = app.Angle3.Value;
            app.angle_4 = app.Angle4.Value;
            app.angle_vector = [deg2rad(0), deg2rad(0), deg2rad(0), deg2rad(0)];

            % Initialiser le graphique
            perc_RPM = linspace(0, 100, 1000);
            xlabel(app.AvD_plot, '%RPM');
            ylabel(app.AvD_plot, 'Thrust (N)');
            title(app.AvD_plot, 'Motor Performance AvD');
            plot(app.AvD_plot, perc_RPM, app.thrust_func(perc_RPM), 'b--');
            grid(app.AvD_plot, 'on');

            xlabel(app.AvG_plot, '%RPM');
            ylabel(app.AvG_plot, 'Thrust (N)');
            title(app.AvG_plot, 'Motor Performance AvG');
            plot(app.AvG_plot, perc_RPM, app.thrust_func(perc_RPM), 'b--');
            grid(app.AvG_plot, 'on');

            xlabel(app.ArD_plot, '%RPM');
            ylabel(app.ArD_plot, 'Thrust (N)');
            title(app.ArD_plot, 'Motor Performance ArD');
            plot(app.ArD_plot, perc_RPM, app.thrust_func(perc_RPM), 'b--');
            grid(app.ArD_plot, 'on');

            xlabel(app.ArG_plot, '%RPM');
            ylabel(app.ArG_plot, 'Thrust (N)');
            title(app.ArG_plot, 'Motor Performance ArG');
            plot(app.ArG_plot, perc_RPM, app.thrust_func(perc_RPM), 'b--');
            grid(app.ArG_plot, 'on');

            % initialisation centre de gravité
            app.nbr_components = 11;
            app.mass_components = zeros(1, app.nbr_components);
            app.CG_components = zeros(app.nbr_components, 3);
            app.CG_motor = zeros(4, 3);

            % état par défault des sliders
            app.AvDRPMSlider.Enable = 'off';
            app.AvGRPMSlider.Enable = 'off';
            app.ArDRPMSlider.Enable = 'off';
            app.ArGRPMSlider.Enable = 'off';

            % initialisation des vecteurs de normes
            app.weight_norm = zeros(3, app.nbr_components);

            % initialisation des %RPM des moteurs
            app.perc_RPM_AvD = 0;
            app.perc_RPM_AvG = 0;
            app.perc_RPM_ArD = 0;
            app.perc_RPM_ArG = 0;

            % initialisation des normes des forces de poussée
            app.f_norm_AvD = 0;
            app.f_norm_AvG = 0;
            app.f_norm_ArD = 0;
            app.f_norm_ArG = 0;

            % initialisation thrust_mot
            app.thrust_AvD = zeros(3, 1);
            app.thrust_AvG = zeros(3, 1);
            app.thrust_ArD = zeros(3, 1);
            app.thrust_ArG = zeros(3, 1);

            % initialisation de la matrice des forces des moteurs
            app.total_thrust_norm = zeros(3, 4);

            % initialisation FLabel : 
            app.FxLabel.Text = "Fx = 0 [N]";
            app.FyLabel.Text = "Fy = 0 [N]";
            app.FzLabel.Text = "Fz = 0 [N]";

            % initialisation MLabel:
            app.MxLabel.Text = "Mx = 0 [N.m]";
            app.MyLabel.Text = "My = 0 [N.m]";
            app.MzLabel.Text = "Mz = 0 [N.m]";
        end

        % Value changed function: AvantdroitCheckBox
        function AvantdroitCheckBoxValueChanged(app, event)
            app.state_av_d = app.AvantdroitCheckBox.Value;

            delete(app.patch_av_d_motor);
            delete(app.patch_av_d_tube);
            delete(app.patch_av_d_box)
            delete(app.patch_grav_center_av_d);
            delete(app.norm_weight_AvD);
            delete(app.thrust_AvD_norm);

            if app.state_av_d == 1 || app.angle_1_changed == true
                disp("génération moteur avant droit")

                % Rendre modifiable le slider
                app.AvDRPMSlider.Enable = 'on';

                % visualisation des performances
                thrust = app.thrust_func(app.perc_RPM_AvD);
                hold(app.AvD_plot, 'on')
                delete(app.percRPM_plot_AvD)
                app.percRPM_plot_AvD = scatter(app.AvD_plot, app.perc_RPM_AvD, thrust, 'MarkerFaceColor', 'r');

                [Vertices_av_d_motor, Vertices_av_d_tube, Vertices_av_d_box, PivotPoint, G_sm_AvD, G_m_AvD] = app.DefVeticesMotor(1);
                
                % rotation du moteur AvD
                Vertices_av_d_motor = (app.motor_rotation(1).' * (Vertices_av_d_motor - PivotPoint(1, :)).' + PivotPoint(1, :).').';
                
                hold(app.Plot3D, 'on')
                app.patch_grav_center_av_d = plot3(app.Plot3D, G_sm_AvD(1), G_sm_AvD(2), G_sm_AvD(3), 'o', 'MarkerSize', 5, 'MarkerEdgeColor', 'k', 'MarkerFaceColor', 'k');
                app.patch_av_d_motor = patch(app.Plot3D, 'Vertices', Vertices_av_d_motor, 'Faces', app.faces, 'FaceColor', 'r', 'FaceAlpha', 0.5);
                app.patch_av_d_tube = patch(app.Plot3D, 'Vertices', Vertices_av_d_tube, 'Faces', app.faces, 'FaceColor', 'b', 'FaceAlpha', 0.5);
                app.patch_av_d_box = patch(app.Plot3D, 'Vertices', Vertices_av_d_box, 'Faces', app.faces, 'FaceColor', 'g', 'FaceAlpha', 0.5);

                % assignation de la masse du moteur AvD
                app.mass_AvD = 0.145;
                app.weight_AvD = app.mass_AvD * app.grav;
                app.norm_weight_AvD = quiver3(app.Plot3D, G_sm_AvD(1), G_sm_AvD(2), G_sm_AvD(3), 0, 0, -0.5, 'Color', [0.68, 0.85, 0.90], 'LineWidth', 2);
                app.weight_norm(:, 1) = [0; 0; -app.weight_AvD];

                % modification du centre de gravité
                app.mass_components(1) = app.mass_AvD;
                app.CG_components(1, :) = G_sm_AvD;
                app.CG_motor(1, :) = G_m_AvD;
                app.updateTotalCG();

                % force de pousée des moteurs
                app.f_norm_AvD = app.thrust_func(app.perc_RPM_AvD);
                app.thrust_AvD = app.f_norm_AvD .* [cos(app.angle_vector(1)); 0; sin(app.angle_vector(1))];
                app.total_thrust_norm(:, 1) = app.thrust_AvD;
                app.UpdateResultante()

                % norme de poussée sur Plot3D
                delete(app.thrust_AvD_norm);
                app.thrust_AvD_norm = quiver3(app.Plot3D, G_m_AvD(1), G_m_AvD(2), G_m_AvD(3), app.thrust_AvD(1), app.thrust_AvD(2), app.thrust_AvD(3), 'Color', 'k', 'LineWidth', 2);
            else

                % modification du centre de gravité
                app.mass_components(1) = 0;
                app.CG_components(1, :) = [0, 0, 0];
                app.CG_motor(1, :) = [0, 0, 0];
                app.weight_norm(:, 1) = zeros(3, 1);
                app.updateTotalCG();

                % désactivation du slider
                app.AvDRPMSlider.Enable = 'off';
                app.AvDRPMSlider.Value = 0;

                % force de pousée des moteusrs
                app.thrust_AvD = zeros(3, 1);
                app.total_thrust_norm(:, 1) = zeros(3, 1);
                app.UpdateResultante()

                if ~isempty(app.patch_av_d_motor) && isvalid(app.patch_av_d_motor)
                    disp("suppression moteur avant droit")
                    delete(app.patch_grav_center_av_d);
                    delete(app.patch_av_d_motor);
                    delete(app.patch_av_d_tube);
                    delete(app.patch_av_d_box);
                    delete(app.norm_weight_AvD);
                    delete(app.thrust_AvD_norm);
                end
            end
        end

        % Value changed function: AvantgaucheCheckBox
        function AvantgaucheCheckBoxValueChanged(app, event)
            app.state_av_g = app.AvantgaucheCheckBox.Value;
        
            delete(app.patch_av_g_motor);
            delete(app.patch_av_g_tube);
            delete(app.patch_av_g_box);
            delete(app.patch_grav_center_av_g);
            delete(app.norm_weight_AvG);
            delete(app.thrust_AvG_norm);

            if app.state_av_g == 1 || app.angle_2_changed == true
                disp("génération moteur avant gauche")

                % Rendre modifiable le slider
                app.AvGRPMSlider.Enable = 'on';

                [Vertices_av_g_motor, Vertices_av_g_tube, Vertices_av_g_box, PivotPoint, G_sm_AvG, G_m_AvG] = app.DefVeticesMotor(2);

                % rotation du moteur AvG
                Vertices_av_g_motor = (app.motor_rotation(2).' * (Vertices_av_g_motor - PivotPoint(1, :)).' + PivotPoint(1, :).').';

                hold(app.Plot3D, 'on')
                app.patch_grav_center_av_g = plot3(app.Plot3D, G_sm_AvG(1), G_sm_AvG(2), G_sm_AvG(3), 'o', 'MarkerSize', 5, 'MarkerEdgeColor', 'k', 'MarkerFaceColor', 'k');
                app.patch_av_g_motor = patch(app.Plot3D, 'Vertices', Vertices_av_g_motor, 'Faces', app.faces, 'FaceColor', 'r', 'FaceAlpha', 0.5);
                app.patch_av_g_tube = patch(app.Plot3D, 'Vertices', Vertices_av_g_tube, 'Faces', app.faces, 'FaceColor', 'b', 'FaceAlpha', 0.5);
                app.patch_av_g_box = patch(app.Plot3D, 'Vertices', Vertices_av_g_box, 'Faces', app.faces, 'FaceColor', 'g', 'FaceAlpha', 0.5);

                % assignation de la masse du moteur AvG
                app.mass_AvG = 0.145;
                app.weight_AvG = app.mass_AvG * app.grav;
                app.norm_weight_AvG = quiver3(app.Plot3D, G_sm_AvG(1), G_sm_AvG(2), G_sm_AvG(3), 0, 0, -0.5, 'Color', [0.68, 0.85, 0.90], 'LineWidth', 2);
                app.weight_norm(:, 2) = [0; 0; -app.weight_AvG];

                % modification du centre de gravité
                app.mass_components(2) = app.mass_AvG;
                app.CG_components(2, :) = G_sm_AvG;
                app.CG_motor(2, :) = G_m_AvG;
                app.updateTotalCG();

                % force de pousée des moteurs
                app.f_norm_AvG = app.thrust_func(app.perc_RPM_AvG);
                app.thrust_AvG = app.f_norm_AvG .* [cos(app.angle_vector(2)); 0; sin(app.angle_vector(2))];     
                app.total_thrust_norm(:, 2) = app.thrust_AvG;
                app.UpdateResultante()

                % norme de poussée sur Plot3D
                delete(app.thrust_AvG_norm);
                app.thrust_AvG_norm = quiver3(app.Plot3D, G_m_AvG(1), G_m_AvG(2), G_m_AvG(3), app.thrust_AvG(1), app.thrust_AvG(2), app.thrust_AvG(3), 'Color', 'k', 'LineWidth', 2);
            else

                % modification du centre de gravité
                app.mass_components(2) = 0;
                app.CG_components(2, :) = [0, 0, 0];
                app.CG_motor(2, :) = [0, 0, 0];
                app.weight_norm(:, 2) = zeros(3, 1);
                app.updateTotalCG();

                % désactivation du slider
                app.AvGRPMSlider.Enable = 'off';
                app.AvGRPMSlider.Value = 0;

                % force de pousée des moteurs
                app.thrust_AvG = zeros(3, 1);
                app.total_thrust_norm(:, 2) = zeros(3, 1);
                app.UpdateResultante()

                if ~isempty(app.patch_av_g_motor) && isvalid(app.patch_av_g_motor)
                    disp("suppression moteur avant gauche")
                    delete(app.patch_grav_center_av_g);
                    delete(app.patch_av_g_motor);
                    delete(app.patch_av_g_tube);
                    delete(app.patch_av_g_box);
                    delete(app.norm_weight_AvG);
                    delete(app.thrust_AvG_norm);
                end
            end     
        end

        % Value changed function: ArriredroitCheckBox
        function ArriredroitCheckBoxValueChanged(app, event)
            app.state_ar_d = app.ArriredroitCheckBox.Value;

            delete(app.patch_ar_d_motor);
            delete(app.patch_ar_d_tube);
            delete(app.patch_ar_d_box);
            delete(app.patch_grav_center_ar_d);
            delete(app.norm_weight_ArD);
            delete(app.thrust_ArD_norm);

            if app.state_ar_d == 1 || app.angle_3_changed == true
                disp("génération moteur arrière droit")

                % Rendre modifiable le slider
                app.ArDRPMSlider.Enable = 'on';

                [Vertices_ar_d_motor, Vertices_ar_d_tube, Vertices_ar_d_box, PivotPoint, G_sm_ArD, G_m_ArD] = app.DefVeticesMotor(3);

                % rotation du moteur ArD
                Vertices_ar_d_motor = (app.motor_rotation(3).' * (Vertices_ar_d_motor - PivotPoint(1, :)).' + PivotPoint(1, :).').';

                hold(app.Plot3D, 'on')
                app.patch_grav_center_ar_d = plot3(app.Plot3D, G_sm_ArD(1), G_sm_ArD(2), G_sm_ArD(3), 'o', 'MarkerSize', 5, 'MarkerEdgeColor', 'k', 'MarkerFaceColor', 'k');
                app.patch_ar_d_motor = patch(app.Plot3D, 'Vertices', Vertices_ar_d_motor, 'Faces', app.faces, 'FaceColor', 'r', 'FaceAlpha', 0.5);
                app.patch_ar_d_tube = patch(app.Plot3D, 'Vertices', Vertices_ar_d_tube, 'Faces', app.faces, 'FaceColor', 'b', 'FaceAlpha', 0.5);
                app.patch_ar_d_box = patch(app.Plot3D, 'Vertices', Vertices_ar_d_box, 'Faces', app.faces, 'FaceColor', 'g', 'FaceAlpha', 0.5);
            
                % assignation de la masse du moteur ArD
                app.mass_ArD = 0.145;
                app.weight_ArD = app.mass_ArD * app.grav;
                app.norm_weight_ArD = quiver3(app.Plot3D, G_sm_ArD(1), G_sm_ArD(2), G_sm_ArD(3), 0, 0, -0.5, 'Color', [0.68, 0.85, 0.90], 'LineWidth', 2);
                app.weight_norm(:, 3) = [0; 0; -app.weight_ArD];

                % modification du centre de gravité
                app.mass_components(3) = app.mass_ArD;
                app.CG_components(3, :) = G_sm_ArD;
                app.CG_motor(3, :) = G_m_ArD;
                app.updateTotalCG();

                % force de pousée des moteurs
                app.f_norm_ArD = app.thrust_func(app.perc_RPM_ArD);
                app.thrust_ArD = app.f_norm_ArD .* [cos(app.angle_vector(3)); 0; sin(app.angle_vector(3))];
                app.total_thrust_norm(:, 3) = app.thrust_ArD;
                app.UpdateResultante()

                % norme de poussée sur Plot3D
                delete(app.thrust_ArD_norm);
                app.thrust_ArD_norm = quiver3(app.Plot3D, G_m_ArD(1), G_m_ArD(2), G_m_ArD(3), app.thrust_ArD(1), app.thrust_ArD(2), app.thrust_ArD(3), 'Color', 'k', 'LineWidth', 2);
            else

                % modification du centre de gravité
                app.mass_components(3) = 0;
                app.CG_components(3, :) = [0, 0, 0];
                app.CG_motor(3, :) = [0, 0, 0];
                app.weight_norm(:, 3) = zeros(3, 1);
                app.updateTotalCG();

                % désactivation du slider
                app.ArDRPMSlider.Enable = 'off';
                app.ArDRPMSlider.Value = 0;

                % force de pousée des moteurs
                app.thrust_ArD = zeros(3, 1);
                app.total_thrust_norm(:, 3) = zeros(3, 1);
                app.UpdateResultante()

                if ~isempty(app.patch_ar_d_motor) && isvalid(app.patch_ar_d_motor)
                    disp("suppression moteur arrière droit")
                    delete(app.patch_grav_center_ar_d);
                    delete(app.patch_ar_d_motor);
                    delete(app.patch_ar_d_tube);
                    delete(app.patch_ar_d_box);
                    delete(app.norm_weight_ArD);
                    delete(app.thrust_ArD_norm);
                end
            end
        end

        % Value changed function: ArriregaucheCheckBox
        function ArriregaucheCheckBoxValueChanged(app, event)
            app.state_ar_g = app.ArriregaucheCheckBox.Value;

            delete(app.patch_ar_g_motor);
            delete(app.patch_ar_g_tube);
            delete(app.patch_ar_g_box);
            delete(app.patch_grav_center_ar_g);
            delete(app.norm_weight_ArG);
            delete(app.thrust_ArG_norm);

            if app.state_ar_g == 1 || app.angle_4_changed == true
                disp("génération moteur arrière droit")

                % % Rendre modifiable le slider
                app.ArGRPMSlider.Enable = 'on';

                [Vertices_ar_g_motor, Vertices_ar_g_tube, Vertices_ar_g_box, PivotPoint, G_sm_ArG, G_m_ArG] = app.DefVeticesMotor(4);

                % rotation du moteur ArG
                Vertices_ar_g_motor = (app.motor_rotation(4).' * (Vertices_ar_g_motor - PivotPoint(1, :)).' + PivotPoint(1, :).').';

                hold(app.Plot3D, 'on')
                app.patch_grav_center_ar_g = plot3(app.Plot3D, G_sm_ArG(1), G_sm_ArG(2), G_sm_ArG(3), 'o', 'MarkerSize', 5, 'MarkerEdgeColor', 'k', 'MarkerFaceColor', 'k');
                app.patch_ar_g_motor = patch(app.Plot3D, 'Vertices', Vertices_ar_g_motor, 'Faces', app.faces, 'FaceColor', 'r', 'FaceAlpha', 0.5);
                app.patch_ar_g_tube = patch(app.Plot3D, 'Vertices', Vertices_ar_g_tube, 'Faces', app.faces, 'FaceColor', 'b', 'FaceAlpha', 0.5);
                app.patch_ar_g_box = patch(app.Plot3D, 'Vertices', Vertices_ar_g_box, 'Faces', app.faces, 'FaceColor', 'g', 'FaceAlpha', 0.5);
            
                % assignation de la masse du moteur ArG
                app.mass_ArG = 0.145;
                app.weight_ArG = app.mass_ArG * app.grav;
                app.norm_weight_ArG = quiver3(app.Plot3D, G_sm_ArG(1), G_sm_ArG(2), G_sm_ArG(3), 0, 0, -0.5, 'Color', [0.68, 0.85, 0.90], 'LineWidth', 2);
                app.weight_norm(:, 4) = [0; 0; -app.weight_ArG];

                % modification du centre de gravité
                app.mass_components(4) = app.mass_ArG;
                app.CG_components(4, :) = G_sm_ArG;
                app.CG_motor(4, :) = G_m_ArG;
                app.updateTotalCG();

                % force de pousée des moteurs
                app.f_norm_ArG = app.thrust_func(app.perc_RPM_ArG);
                app.thrust_ArG = app.f_norm_ArG .* [cos(app.angle_vector(4)); 0; sin(app.angle_vector(4))];
                app.total_thrust_norm(:, 4) = app.thrust_ArG;
                app.UpdateResultante()

                % norme de poussée sur Plot3D
                delete(app.thrust_ArG_norm);
                app.thrust_ArG_norm = quiver3(app.Plot3D, G_m_ArG(1), G_m_ArG(2), G_m_ArG(3), app.thrust_ArG(1), app.thrust_ArG(2), app.thrust_ArG(3), 'Color', 'k', 'LineWidth', 2);
            else

                % modification du centre de gravité
                app.mass_components(4) = 0;
                app.CG_components(4, :) = [0, 0, 0];
                app.CG_motor(4, :) = [0, 0, 0];
                app.weight_norm(:, 4) = zeros(3, 1);
                app.updateTotalCG();    

                % désactivation du slider
                app.ArGRPMSlider.Enable = 'off';
                app.ArGRPMSlider.Value = 0;

                % force de pousée des moteurs
                app.thrust_ArG = zeros(3, 1);
                app.total_thrust_norm(:, 4) = zeros(3, 1);
                app.UpdateResultante()

                if ~isempty(app.patch_ar_g_motor) && isvalid(app.patch_ar_g_motor)
                    disp("suppression moteur arrière droit")
                    delete(app.patch_grav_center_ar_g);
                    delete(app.patch_ar_g_motor);
                    delete(app.patch_ar_g_tube);
                    delete(app.patch_ar_g_box);
                    delete(app.norm_weight_ArG);
                    delete(app.thrust_ArG_norm);
                end
            end
        end

        % Value changed function: Angle1
        function Angle1ValueChanged(app, event)
            app.angle_1 = deg2rad(app.Angle1.Value);
            app.angle_vector(1) = app.angle_1;
            app.angle_1_changed = true;
            app.AvantdroitCheckBoxValueChanged(app)
        end

        % Value changed function: Angle2
        function Angle2ValueChanged(app, event)
            app.angle_2 = deg2rad(app.Angle2.Value);
            app.angle_vector(2) = app.angle_2;
            app.angle_2_changed = true;
            app.AvantgaucheCheckBoxValueChanged(app)
        end

        % Value changed function: Angle3
        function Angle3ValueChanged(app, event)
            app.angle_3 = deg2rad(app.Angle3.Value);
            app.angle_vector(3) = app.angle_3;
            app.angle_3_changed = true;
            app.ArriredroitCheckBoxValueChanged(app)
        end

        % Value changed function: Angle4
        function Angle4ValueChanged(app, event)
            app.angle_4 = deg2rad(app.Angle4.Value);
            app.angle_vector(4) = app.angle_4;
            app.angle_4_changed = true;
            app.ArriregaucheCheckBoxValueChanged(app)
        end

        % Value changed function: NacelleCheckBox
        function NacelleCheckBoxValueChanged(app, event)
            app.state_nacelle = app.NacelleCheckBox.Value;

            delete(app.patch_nacelle)
            delete(app.norm_weight_nacelle)

            if app.state_nacelle == 1
                
                hold(app.Plot3D, 'on')
                disp("génération de la nacelle")

                Vertices_nacelle = [
                    app.nacelle_position.x1, app.nacelle_position.y1, app.nacelle_position.z1;
                    app.nacelle_position.x2, app.nacelle_position.y1, app.nacelle_position.z1;
                    app.nacelle_position.x2, app.nacelle_position.y2, app.nacelle_position.z1;
                    app.nacelle_position.x1, app.nacelle_position.y2, app.nacelle_position.z1;
                    app.nacelle_position.x1, app.nacelle_position.y1, app.nacelle_position.z2;
                    app.nacelle_position.x2, app.nacelle_position.y1, app.nacelle_position.z2;
                    app.nacelle_position.x2, app.nacelle_position.y2, app.nacelle_position.z2;
                    app.nacelle_position.x1, app.nacelle_position.y2, app.nacelle_position.z2;
                    ];

                app.patch_nacelle = patch(app.Plot3D, 'Vertices', Vertices_nacelle, 'Faces', app.faces, 'FaceColor', [0.5, 0, 0], 'FaceAlpha', 0.5);

                G_nacelle = [(app.nacelle_position.x1 + app.nacelle_position.x2)/2, (app.nacelle_position.y1 + app.nacelle_position.y2)/2, (app.nacelle_position.z1 + app.nacelle_position.z2)/2];

                % assignation de la masse du moteur ArG
                app.mass_nacelle = 0.5;
                app.weight_nacelle = app.mass_nacelle * app.grav;
                app.norm_weight_nacelle = quiver3(app.Plot3D, G_nacelle(1), G_nacelle(2), G_nacelle(3), 0, 0, -0.5, 'Color', [0.68, 0.85, 0.90], 'LineWidth', 2);
                hold(app.Plot3D, 'on')
                app.weight_norm(:, 5) = [0; 0; -app.weight_nacelle];

                % modification du centre de gravité
                app.mass_components(5) = app.mass_nacelle;
                app.CG_components(5, :) = G_nacelle;
                app.updateTotalCG();
                app.UpdateResultante();
            else
                disp("suppression de la nacelle")

                % modification du centre de gravité
                app.mass_components(5) = 0;
                app.CG_components(5, :) = [0, 0, 0];
                app.weight_norm(:, 5) = zeros(3, 1);
                app.updateTotalCG();
                app.UpdateResultante();

                if ~isempty(app.patch_nacelle) && isvalid(app.patch_nacelle)
                    delete(app.patch_nacelle)
                    delete(app.norm_weight_nacelle)
                end
            end
        end

        % Value changed function: AvDRPMSlider
        function AvDRPMSliderValueChanged(app, event)
            app.perc_RPM_AvD = app.AvDRPMSlider.Value;
            app.AvantdroitCheckBoxValueChanged()
        end

        % Value changed function: AvGRPMSlider
        function AvGRPMSliderValueChanged(app, event)
            app.perc_RPM_AvG = app.AvGRPMSlider.Value;
            app.AvantgaucheCheckBoxValueChanged()
        end

        % Value changed function: ArDRPMSlider
        function ArDRPMSliderValueChanged(app, event)
            app.perc_RPM_ArD = app.ArDRPMSlider.Value;
            app.ArriredroitCheckBoxValueChanged()
        end

        % Value changed function: ArGRPMSlider
        function ArGRPMSliderValueChanged(app, event)
            app.perc_RPM_ArG = app.ArGRPMSlider.Value;
            app.ArriregaucheCheckBoxValueChanged()
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            % Assurez-vous que les variables existent et sont correctement assignées
            CG_total = app.CG_total;
            CG_components = app.CG_components;
            CG_motor = app.CG_motor;
        
            % Créez le chemin complet pour le fichier .mat
            filename = fullfile(app.data_folder, "CG_data.mat");
        
            % Sauvegardez les variables dans le fichier .mat
            save(filename, 'CG_total', 'CG_components', 'CG_motor');
        
            % Optionnel: Afficher un message de confirmation
            uialert(app.UIFigure, 'Data exported successfully!', 'Success');
        end

        % Value changed function: m_ballast_1
        function m_ballast_1ValueChanged(app, event)
            app.mass_ballast_1 = str2double(app.m_ballast_1.Value) / 1e3;

            if app.mass_ballast_1 >= 0.250
                app.mass_ballast_1 = 0.250;
                app.m_ballast_1.Value = '250';
            end

            app.P_ballast_1 = [0; 0; - app.mass_ballast_1 * app.grav];
            app.weight_norm(:, 6) = app.P_ballast_1;

            app.mass_components(6) = app.mass_ballast_1;
            

            % Centre de gravité du premier ballast
            x_CG = (app.ballast_position.x1(1) + app.ballast_position.x2(1))/2;
            y_CG = (app.ballast_position.y1(1) + app.ballast_position.y2(1))/2;
            z_CG = (app.ballast_position.z1(1) + app.ballast_position.z2(1))/2;

            CG_ballast_1 = [x_CG, y_CG, z_CG];
            app.CG_components(6, :) = CG_ballast_1;

            delete(app.P_ballast_1_norm)
            app.P_ballast_1_norm = quiver3(app.Plot3D, CG_ballast_1(1), CG_ballast_1(2), CG_ballast_1(3), app.P_ballast_1(1), app.P_ballast_1(2), app.P_ballast_1(3), 'Color', [0.68, 0.85, 0.90], 'LineWidth', 2);

            app.updateTotalCG();
            app.UpdateResultante();
        end

        % Value changed function: m_ballast_2
        function m_ballast_2ValueChanged(app, event)
            app.mass_ballast_2 = str2double(app.m_ballast_2.Value) / 1e3;

            if app.mass_ballast_2 >= 0.250
                app.mass_ballast_2 = 0.250;
                app.m_ballast_2.Value = '250';
            end

            app.P_ballast_2 = [0; 0; - app.mass_ballast_2 * app.grav];
            app.weight_norm(:, 7) = app.P_ballast_2;

            app.mass_components(7) = app.mass_ballast_2;

            % Centre de gravité du deuxième ballast
            x_CG = (app.ballast_position.x1(2) + app.ballast_position.x2(2))/2;
            y_CG = (app.ballast_position.y1(2) + app.ballast_position.y2(2))/2;
            z_CG = (app.ballast_position.z1(2) + app.ballast_position.z2(2))/2;

            CG_ballast_2 = [x_CG, y_CG, z_CG];
            app.CG_components(7, :) = CG_ballast_2;

            delete(app.P_ballast_2_norm)
            app.P_ballast_2_norm = quiver3(app.Plot3D, CG_ballast_2(1), CG_ballast_2(2), CG_ballast_2(3), app.P_ballast_2(1), app.P_ballast_2(2), app.P_ballast_2(3), 'Color', [0.68, 0.85, 0.90], 'LineWidth', 2);

            app.updateTotalCG();
            app.UpdateResultante();
        end

        % Value changed function: m_ballast_3
        function m_ballast_3ValueChanged(app, event)
            app.mass_ballast_3 = str2double(app.m_ballast_3.Value) / 1e3;

            if app.mass_ballast_3 >= 0.250
                app.mass_ballast_3 = 0.250;
                app.m_ballast_3.Value = '250';
            end

            app.P_ballast_3 = [0; 0; - app.mass_ballast_3 * app.grav];
            app.weight_norm(:, 8) = app.P_ballast_3;

            app.mass_components(8) = app.mass_ballast_3;

            % Centre de gravité du troisième ballast
            x_CG = (app.ballast_position.x1(3) + app.ballast_position.x2(3))/2;
            y_CG = (app.ballast_position.y1(3) + app.ballast_position.y2(3))/2;
            z_CG = (app.ballast_position.z1(3) + app.ballast_position.z2(3))/2;

            CG_ballast_3 = [x_CG, y_CG, z_CG];
            app.CG_components(8, :) = CG_ballast_3;

            delete(app.P_ballast_3_norm)
            app.P_ballast_3_norm = quiver3(app.Plot3D, CG_ballast_3(1), CG_ballast_3(2), CG_ballast_3(3), app.P_ballast_3(1), app.P_ballast_3(2), app.P_ballast_3(3), 'Color', [0.68, 0.85, 0.90], 'LineWidth', 2);

            app.updateTotalCG();
            app.UpdateResultante();
        end

        % Value changed function: m_ballast_4
        function m_ballast_4ValueChanged(app, event)
            app.mass_ballast_4 = str2double(app.m_ballast_4.Value) / 1e3;

            if app.mass_ballast_4 >= 0.250
                app.mass_ballast_4 = 0.250;
                app.m_ballast_4.Value = '250';
            end

            app.P_ballast_4 = [0; 0; - app.mass_ballast_4 * app.grav];
            app.weight_norm(:, 9) = app.P_ballast_4;
            
            app.mass_components(9) = app.mass_ballast_4;

            % Centre de gravité du quatrième ballast
            x_CG = (app.ballast_position.x1(4) + app.ballast_position.x2(4))/2;
            y_CG = (app.ballast_position.y1(4) + app.ballast_position.y2(4))/2;
            z_CG = (app.ballast_position.z1(4) + app.ballast_position.z2(4))/2;

            CG_ballast_4 = [x_CG, y_CG, z_CG];
            app.CG_components(9, :) = CG_ballast_4;

            delete(app.P_ballast_4_norm)
            app.P_ballast_4_norm = quiver3(app.Plot3D, CG_ballast_4(1), CG_ballast_4(2), CG_ballast_4(3), app.P_ballast_4(1), app.P_ballast_4(2), app.P_ballast_4(3), 'Color', [0.68, 0.85, 0.90], 'LineWidth', 2);

            app.updateTotalCG();
            app.UpdateResultante();
        end

        % Button pushed function: GenerateMomentButton
        function GenerateMomentButtonPushed(app, event)
            app.distance = app.CG_total - app.CG_components;

            app.moment = zeros(3, app.nbr_components);
            for i = 1:app.nbr_components

                % synchronisation des moteurs en diagonale
                if i == 2 || i == 3
                    sens = -1;
                else
                    sens = 1;
                end

                m_i =  sens * cross(app.distance(i, :), app.forces_ext(:, i)')';
                app.moment(:, i) = m_i;
            end
                
            app.moment_res = [app.moment(1, :), app.moment(2, :), app.moment(3, :)];
            delete(app.moment_res_norm)
            app.moment_res_norm = quiver3(app.Plot3D, app.CG_total(1), app.CG_total(2), app.CG_total(3), app.moment_res(1), app.moment_res(2), app.moment_res(3), 'Color', 'm', 'LineWidth', 2);

            disp(app.moment)

            app.MxLabel.Text = "Mx = " + sum(app.moment_res(1)) + " [N.m]";
            app.MyLabel.Text = "My = " + sum(app.moment_res(2)) + " [N.m]";
            app.MzLabel.Text = "Mz = " + sum(app.moment_res(3)) + " [N.m]";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Reprsentation3DTab = uitab(app.TabGroup);
            app.Reprsentation3DTab.Title = 'Représentation 3D';
            app.GridLayout2 = uigridlayout(app.Reprsentation3DTab);
            app.Plot3D = uiaxes(app.GridLayout2);
            title(app.Plot3D, 'Title')
            xlabel(app.Plot3D, 'X')
            ylabel(app.Plot3D, 'Y')
            zlabel(app.Plot3D, 'Z')
            app.GestiondescomposantsLabel = uilabel(app.GridLayout2);
            app.GestiondescomposantsLabel.Text = 'Gestion des composants';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.AvantdroitCheckBox = uicheckbox(app.GridLayout4);
            app.AvantdroitCheckBox.ValueChangedFcn = createCallbackFcn(app, @AvantdroitCheckBoxValueChanged, true);
            app.AvantdroitCheckBox.Text = 'Avant droit';
            app.AvantgaucheCheckBox = uicheckbox(app.GridLayout4);
            app.AvantgaucheCheckBox.ValueChangedFcn = createCallbackFcn(app, @AvantgaucheCheckBoxValueChanged, true);
            app.AvantgaucheCheckBox.Text = 'Avant gauche';
            app.ArriredroitCheckBox = uicheckbox(app.GridLayout4);
            app.ArriredroitCheckBox.ValueChangedFcn = createCallbackFcn(app, @ArriredroitCheckBoxValueChanged, true);
            app.ArriredroitCheckBox.Text = 'Arrière droit';
            app.ArriregaucheCheckBox = uicheckbox(app.GridLayout4);
            app.ArriregaucheCheckBox.ValueChangedFcn = createCallbackFcn(app, @ArriregaucheCheckBoxValueChanged, true);
            app.ArriregaucheCheckBox.Text = 'Arrière gauche';
            app.MoteurLabel = uilabel(app.GridLayout4);
            app.MoteurLabel.Text = 'Moteur';
            app.AngleLabel = uilabel(app.GridLayout4);
            app.AngleLabel.Text = 'Angle';
            app.Angle1 = uieditfield(app.GridLayout4, 'numeric');
            app.Angle1.ValueChangedFcn = createCallbackFcn(app, @Angle1ValueChanged, true);
            app.Angle2 = uieditfield(app.GridLayout4, 'numeric');
            app.Angle2.ValueChangedFcn = createCallbackFcn(app, @Angle2ValueChanged, true);
            app.Angle3 = uieditfield(app.GridLayout4, 'numeric');
            app.Angle3.ValueChangedFcn = createCallbackFcn(app, @Angle3ValueChanged, true);
            app.Angle4 = uieditfield(app.GridLayout4, 'numeric');
            app.Angle4.ValueChangedFcn = createCallbackFcn(app, @Angle4ValueChanged, true);
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.AvDRPMSliderLabel = uilabel(app.GridLayout6);
            app.AvDRPMSliderLabel.Text = 'AvD [%RPM]';
            app.AvDRPMSlider = uislider(app.GridLayout6);
            app.AvDRPMSlider.ValueChangedFcn = createCallbackFcn(app, @AvDRPMSliderValueChanged, true);
            app.AvGRPMSliderLabel = uilabel(app.GridLayout6);
            app.AvGRPMSliderLabel.Text = 'AvG [%RPM]';
            app.AvGRPMSlider = uislider(app.GridLayout6);
            app.AvGRPMSlider.ValueChangedFcn = createCallbackFcn(app, @AvGRPMSliderValueChanged, true);
            app.ArDRPMSliderLabel = uilabel(app.GridLayout6);
            app.ArDRPMSliderLabel.Text = 'ArD [%RPM]';
            app.ArDRPMSlider = uislider(app.GridLayout6);
            app.ArDRPMSlider.ValueChangedFcn = createCallbackFcn(app, @ArDRPMSliderValueChanged, true);
            app.ArGRPMSliderLabel = uilabel(app.GridLayout6);
            app.ArGRPMSliderLabel.Text = 'ArG [%RPM]';
            app.ArGRPMSlider = uislider(app.GridLayout6);
            app.ArGRPMSlider.ValueChangedFcn = createCallbackFcn(app, @ArGRPMSliderValueChanged, true);
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.NacelleLabel = uilabel(app.GridLayout7);
            app.NacelleLabel.Text = 'Nacelle';
            app.NacelleCheckBox = uicheckbox(app.GridLayout7);
            app.NacelleCheckBox.ValueChangedFcn = createCallbackFcn(app, @NacelleCheckBoxValueChanged, true);
            app.NacelleCheckBox.Text = 'Nacelle';
            app.GridLayout10 = uigridlayout(app.GridLayout3);
            app.GridLayout11 = uigridlayout(app.GridLayout10);
            app.Label = uilabel(app.GridLayout11);
            app.Label.Text = '1';
            app.m_ballast_1 = uieditfield(app.GridLayout11, 'text');
            app.m_ballast_1.ValueChangedFcn = createCallbackFcn(app, @m_ballast_1ValueChanged, true);
            app.m_ballast_1.Value = '0';
            app.Label_2 = uilabel(app.GridLayout11);
            app.Label_2.Text = '2';
            app.m_ballast_2 = uieditfield(app.GridLayout11, 'text');
            app.m_ballast_2.ValueChangedFcn = createCallbackFcn(app, @m_ballast_2ValueChanged, true);
            app.m_ballast_2.Value = '0';
            app.Label_3 = uilabel(app.GridLayout11);
            app.Label_3.Text = '3';
            app.m_ballast_3 = uieditfield(app.GridLayout11, 'text');
            app.m_ballast_3.ValueChangedFcn = createCallbackFcn(app, @m_ballast_3ValueChanged, true);
            app.m_ballast_3.Value = '0';
            app.Label_4 = uilabel(app.GridLayout11);
            app.Label_4.Text = '4';
            app.m_ballast_4 = uieditfield(app.GridLayout11, 'text');
            app.m_ballast_4.ValueChangedFcn = createCallbackFcn(app, @m_ballast_4ValueChanged, true);
            app.m_ballast_4.Value = '0';
            app.BallastLabel = uilabel(app.GridLayout10);
            app.BallastLabel.Text = 'Ballast';
            app.GridLayout8 = uigridlayout(app.GridLayout2);
            app.CentredegravitLabel = uilabel(app.GridLayout8);
            app.CentredegravitLabel.Text = 'Centre de gravité : ';
            app.PosGrav = uilabel(app.GridLayout8);
            app.PosGrav.Text = '';
            app.GridLayout9 = uigridlayout(app.GridLayout2);
            app.ExportDataButton = uibutton(app.GridLayout9, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.GenerateMomentButton = uibutton(app.GridLayout9, 'push');
            app.GenerateMomentButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMomentButtonPushed, true);
            app.GenerateMomentButton.Text = 'Generate Moment';
            app.PerformanceTab = uitab(app.TabGroup);
            app.PerformanceTab.Title = 'Performance';
            app.GridLayout5 = uigridlayout(app.PerformanceTab);
            app.AvD_plot = uiaxes(app.GridLayout5);
            title(app.AvD_plot, 'Title')
            xlabel(app.AvD_plot, 'X')
            ylabel(app.AvD_plot, 'Y')
            zlabel(app.AvD_plot, 'Z')
            app.ArD_plot = uiaxes(app.GridLayout5);
            title(app.ArD_plot, 'Title')
            xlabel(app.ArD_plot, 'X')
            ylabel(app.ArD_plot, 'Y')
            zlabel(app.ArD_plot, 'Z')
            app.AvG_plot = uiaxes(app.GridLayout5);
            title(app.AvG_plot, 'Title')
            xlabel(app.AvG_plot, 'X')
            ylabel(app.AvG_plot, 'Y')
            zlabel(app.AvG_plot, 'Z')
            app.ArG_plot = uiaxes(app.GridLayout5);
            title(app.ArG_plot, 'Title')
            xlabel(app.ArG_plot, 'X')
            ylabel(app.ArG_plot, 'Y')
            zlabel(app.ArG_plot, 'Z')
            app.GridLayout12 = uigridlayout(app.GridLayout5);
            app.ForcersultanteLabel = uilabel(app.GridLayout12);
            app.ForcersultanteLabel.Text = 'Force résultante : ';
            app.FxLabel = uilabel(app.GridLayout12);
            app.FxLabel.Text = 'Fx =';
            app.FyLabel = uilabel(app.GridLayout12);
            app.FyLabel.Text = 'Fy =';
            app.FzLabel = uilabel(app.GridLayout12);
            app.FzLabel.Text = 'Fz =';
            app.GridLayout13 = uigridlayout(app.GridLayout5);
            app.MomentrsultanteLabel = uilabel(app.GridLayout13);
            app.MomentrsultanteLabel.Text = 'Moment résultante :';
            app.MxLabel = uilabel(app.GridLayout13);
            app.MxLabel.Text = 'Mx = ';
            app.MyLabel = uilabel(app.GridLayout13);
            app.MyLabel.Text = 'My = ';
            app.MzLabel = uilabel(app.GridLayout13);
            app.MzLabel.Text = 'Mz = ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end